classdef EDI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CloseButton                    matlab.ui.control.Button
        ButtonGroup_8                  matlab.ui.container.ButtonGroup
        Button6_4                      matlab.ui.control.RadioButton
        Button_42                      matlab.ui.control.RadioButton
        Button_38                      matlab.ui.control.RadioButton
        Button_34                      matlab.ui.control.RadioButton
        Button_30                      matlab.ui.control.RadioButton
        Button1_4                      matlab.ui.control.RadioButton
        ButtonGroup_7                  matlab.ui.container.ButtonGroup
        Button6_3                      matlab.ui.control.RadioButton
        Button_41                      matlab.ui.control.RadioButton
        Button_37                      matlab.ui.control.RadioButton
        Button_33                      matlab.ui.control.RadioButton
        Button_29                      matlab.ui.control.RadioButton
        Button_26                      matlab.ui.control.RadioButton
        ButtonGroup_6                  matlab.ui.container.ButtonGroup
        Button6_2                      matlab.ui.control.RadioButton
        Button_40                      matlab.ui.control.RadioButton
        Button_36                      matlab.ui.control.RadioButton
        Button_32                      matlab.ui.control.RadioButton
        Button_28                      matlab.ui.control.RadioButton
        Button1_2                      matlab.ui.control.RadioButton
        ButtonGroup_5                  matlab.ui.container.ButtonGroup
        Button6                        matlab.ui.control.RadioButton
        Button_39                      matlab.ui.control.RadioButton
        Button_35                      matlab.ui.control.RadioButton
        Button_31                      matlab.ui.control.RadioButton
        Button_27                      matlab.ui.control.RadioButton
        Button1                        matlab.ui.control.RadioButton
        Label                          matlab.ui.control.Label
        ToothbrushBrossedentsLabel     matlab.ui.control.Label
        AlwaysleftToujoursgaucheLabel  matlab.ui.control.Label
        UsuallyLeftHabituellementgaucheLabel  matlab.ui.control.Label
        BothEquallyLesdeuxgalitLabel   matlab.ui.control.Label
        UsuallyrightHabituellementdroitLabel  matlab.ui.control.Label
        AlwaysrightToujoursdroitLabel  matlab.ui.control.Label
        ThrowingLancerunObjectLabel    matlab.ui.control.Label
        WritingcrireLabel              matlab.ui.control.Label
        SpoonCuillreLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % Description
        EDIdata % Description
       
        counter % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idz, session)

            app.counter=zeros(1);
            
        end

        % Callback function
        function DoneButtonPushed(app, event)
scorewriting=0;
scorethrowing=0;
scoretoothbrush=0;
scorespoon=0;

for i=1:5;
    
logwriting= app.data.writing.Parent.Buttons(i).Value;
logthrowing= app.data.throwing.Parent.Buttons(i).Value;
logtoothbrush= app.data.toothbrush.Parent.Buttons(i).Value;
logspoon= app.data.spoon.Parent.Buttons(i).Value;

scorewriting=scorewriting+(logwriting*i);
scorethrowing=scorethrowing+(logthrowing*i);
scoretoothbrush=scoretoothbrush+(logtoothbrush*i);
scorespoon=scorespoon+(logspoon*i);
end
app.EDIdata.total=double(scorewriting(1))+ double(scorethrowing(1))+ double(scoretoothbrush(1))+double(scorespoon(1));


app.EDIdata.scorewriting=scorewriting;
app.EDIdata.scorethrowing=scorethrowing;
app.EDIdata.scoretoothbrush=scoretoothbrush;
app.EDIdata.scorespoon=scorespoon;


        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_5.SelectedObject;
            app.data.writing=selectedButton ;
          counter=app.counter;
                        app.counter=counter+1;
                        if app.counter==4;
                            app.CloseButton.Visible = 'on';
                        end
        end

        % Selection changed function: ButtonGroup_6
        function ButtonGroup_6SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_6.SelectedObject;
            app.data.throwing=selectedButton ;
                 counter=app.counter;
                        app.counter=counter+1;
                        if app.counter==4;
                            app.CloseButton.Visible = 'on';
                        end
        end

        % Selection changed function: ButtonGroup_7
        function ButtonGroup_7SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_7.SelectedObject;
            app.data.toothbrush=selectedButton ;
                       counter=app.counter;
                        app.counter=counter+1;
                        if app.counter==4;
                            app.CloseButton.Visible = 'on';
                        end
        end

        % Selection changed function: ButtonGroup_8
        function ButtonGroup_8SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_8.SelectedObject;
            app.data.spoon=selectedButton ;
            counter=app.counter;
                        app.counter=counter+1;
                        if app.counter==4;
                            app.CloseButton.Visible = 'on';
                        end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            scorewriting=0;
scorethrowing=0;
scoretoothbrush=0;
scorespoon=0;

for i=1:5;
    
logwriting= app.data.writing.Parent.Buttons(i).Value;
logthrowing= app.data.throwing.Parent.Buttons(i).Value;
logtoothbrush= app.data.toothbrush.Parent.Buttons(i).Value;
logspoon= app.data.spoon.Parent.Buttons(i).Value;

scorewriting=scorewriting+(logwriting*i);
scorethrowing=scorethrowing+(logthrowing*i);
scoretoothbrush=scoretoothbrush+(logtoothbrush*i);
scorespoon=scorespoon+(logspoon*i);
end
app.EDIdata.total=double(scorewriting(1))+ double(scorethrowing(1))+ double(scoretoothbrush(1))+double(scorespoon(1));

app.EDIdata.scorewriting=scorewriting;
app.EDIdata.scorethrowing=scorethrowing;
app.EDIdata.scoretoothbrush=scoretoothbrush;
app.EDIdata.scorespoon=scorespoon;
       datax=app.EDIdata;

            assignin('base','EDI_data',datax);
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SpoonCuillreLabel = uilabel(app.GridLayout);
            app.SpoonCuillreLabel.Text = 'Spoon/ Cuillère';
            app.WritingcrireLabel = uilabel(app.GridLayout);
            app.WritingcrireLabel.Text = 'Writing / Écrire';
            app.ThrowingLancerunObjectLabel = uilabel(app.GridLayout);
            app.ThrowingLancerunObjectLabel.Text = {'Throwing /'; 'Lancer un Object'};
            app.AlwaysrightToujoursdroitLabel = uilabel(app.GridLayout);
            app.AlwaysrightToujoursdroitLabel.Text = {'Always right/ '; 'Toujours droit'};
            app.UsuallyrightHabituellementdroitLabel = uilabel(app.GridLayout);
            app.UsuallyrightHabituellementdroitLabel.Text = {'Usually right/ '; 'Habituellement'; 'droit'};
            app.BothEquallyLesdeuxgalitLabel = uilabel(app.GridLayout);
            app.BothEquallyLesdeuxgalitLabel.Text = {'Both Equally/'; 'Les deux à égalité'};
            app.UsuallyLeftHabituellementgaucheLabel = uilabel(app.GridLayout);
            app.UsuallyLeftHabituellementgaucheLabel.Text = {'Usually Left/'; 'Habituellement'; 'gauche'};
            app.AlwaysleftToujoursgaucheLabel = uilabel(app.GridLayout);
            app.AlwaysleftToujoursgaucheLabel.Text = {'Always left/'; 'Toujours gauche'};
            app.ToothbrushBrossedentsLabel = uilabel(app.GridLayout);
            app.ToothbrushBrossedentsLabel.Text = {'Toothbrush/ '; 'Brosse à dents'};
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Please indicate your preferences in the use of hands in the following '; 'activities or objects/'; 'Veuillez indiquer vos préférences concernant l''utilisation des mains dans les activités ou des '; 'objets suivant :'};
            app.ButtonGroup_5 = uibuttongroup(app.GridLayout);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.Button1 = uiradiobutton(app.ButtonGroup_5);
            app.Button1.Text = '';
            app.Button_27 = uiradiobutton(app.ButtonGroup_5);
            app.Button_27.Text = '';
            app.Button_31 = uiradiobutton(app.ButtonGroup_5);
            app.Button_31.Text = '';
            app.Button_35 = uiradiobutton(app.ButtonGroup_5);
            app.Button_35.Text = '';
            app.Button_39 = uiradiobutton(app.ButtonGroup_5);
            app.Button_39.Text = '';
            app.Button6 = uiradiobutton(app.ButtonGroup_5);
            app.Button6.Visible = 'off';
            app.Button6.Text = 'Button6';
            app.Button6.Value = true;
            app.ButtonGroup_6 = uibuttongroup(app.GridLayout);
            app.ButtonGroup_6.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_6SelectionChanged, true);
            app.Button1_2 = uiradiobutton(app.ButtonGroup_6);
            app.Button1_2.Text = '';
            app.Button_28 = uiradiobutton(app.ButtonGroup_6);
            app.Button_28.Text = '';
            app.Button_32 = uiradiobutton(app.ButtonGroup_6);
            app.Button_32.Text = '';
            app.Button_36 = uiradiobutton(app.ButtonGroup_6);
            app.Button_36.Text = '';
            app.Button_40 = uiradiobutton(app.ButtonGroup_6);
            app.Button_40.Text = '';
            app.Button6_2 = uiradiobutton(app.ButtonGroup_6);
            app.Button6_2.Visible = 'off';
            app.Button6_2.Text = 'Button6';
            app.Button6_2.Value = true;
            app.ButtonGroup_7 = uibuttongroup(app.GridLayout);
            app.ButtonGroup_7.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_7SelectionChanged, true);
            app.Button_26 = uiradiobutton(app.ButtonGroup_7);
            app.Button_26.Text = '';
            app.Button_29 = uiradiobutton(app.ButtonGroup_7);
            app.Button_29.Text = '';
            app.Button_33 = uiradiobutton(app.ButtonGroup_7);
            app.Button_33.Text = '';
            app.Button_37 = uiradiobutton(app.ButtonGroup_7);
            app.Button_37.Text = '';
            app.Button_41 = uiradiobutton(app.ButtonGroup_7);
            app.Button_41.Text = '';
            app.Button6_3 = uiradiobutton(app.ButtonGroup_7);
            app.Button6_3.Visible = 'off';
            app.Button6_3.Text = 'Button6';
            app.Button6_3.Value = true;
            app.ButtonGroup_8 = uibuttongroup(app.GridLayout);
            app.ButtonGroup_8.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_8SelectionChanged, true);
            app.Button1_4 = uiradiobutton(app.ButtonGroup_8);
            app.Button1_4.Text = '';
            app.Button_30 = uiradiobutton(app.ButtonGroup_8);
            app.Button_30.Text = '';
            app.Button_34 = uiradiobutton(app.ButtonGroup_8);
            app.Button_34.Text = '';
            app.Button_38 = uiradiobutton(app.ButtonGroup_8);
            app.Button_38.Text = '';
            app.Button_42 = uiradiobutton(app.ButtonGroup_8);
            app.Button_42.Text = '';
            app.Button6_4 = uiradiobutton(app.ButtonGroup_8);
            app.Button6_4.Visible = 'off';
            app.Button6_4.Text = 'Button6';
            app.Button6_4.Value = true;
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end