classdef KSS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        VigilanceVigilanceLabel    matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        Button9_2                  matlab.ui.control.RadioButton
        Button9                    matlab.ui.control.RadioButton
        Button8                    matlab.ui.control.RadioButton
        Button7                    matlab.ui.control.RadioButton
        Button6                    matlab.ui.control.RadioButton
        Button5                    matlab.ui.control.RadioButton
        Button4                    matlab.ui.control.RadioButton
        Button3                    matlab.ui.control.RadioButton
        Button2                    matlab.ui.control.RadioButton
        Button                     matlab.ui.control.RadioButton
        DoneFiniButton             matlab.ui.control.Button
        PleaseindicatehowyouarefeelingrightnowLabel  matlab.ui.control.Label
        VerysleepygreatefforttokeepalertfightingsleepLabel  matlab.ui.control.Label
        SleepysomeefforttokeepalertLabel  matlab.ui.control.Label
        SleepybutnoefforttokeepalertLabel  matlab.ui.control.Label
        SomesignsofsleepinessQuelquessignesdesomnolenceLabel  matlab.ui.control.Label
        NeitheralertnorsleepyNiveillniendormieLabel  matlab.ui.control.Label
        FairyAlertPluttveillLabel  matlab.ui.control.Label
        AlertveillLabel            matlab.ui.control.Label
        VeryAlertTrsveillLabel     matlab.ui.control.Label
        ExtremelyAlertExtrmementveillLabel  matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        data; % Description

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idz, session, purpose)
     
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
 selectedButton = app.ButtonGroup.SelectedObject;
            app.data=selectedButton;
            app.DoneFiniButton.Visible='on';
            
             
        end

        % Button pushed function: DoneFiniButton
        function DoneFiniButtonPushed(app, event)
    score=0;
            for i=1:9;
val= app.data.Parent.Buttons(i).Value;

score=score+(val*i);
            end

 assignin('base','KSS_data', score);
            close(app.UIFigure);
        end

        % Callback function
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            app.data=selectedButton;
            app.DoneFiniButton.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '9';
            app.ExtremelyAlertExtrmementveillLabel = uilabel(app.GridLayout);
            app.ExtremelyAlertExtrmementveillLabel.Text = 'Extremely Alert / Extrêmement éveillé';
            app.VeryAlertTrsveillLabel = uilabel(app.GridLayout);
            app.VeryAlertTrsveillLabel.Text = 'Very Alert/ Très éveillé';
            app.AlertveillLabel = uilabel(app.GridLayout);
            app.AlertveillLabel.Text = 'Alert / Éveillé  ';
            app.FairyAlertPluttveillLabel = uilabel(app.GridLayout);
            app.FairyAlertPluttveillLabel.Text = 'Fairy Alert/ Plutôt éveillé';
            app.NeitheralertnorsleepyNiveillniendormieLabel = uilabel(app.GridLayout);
            app.NeitheralertnorsleepyNiveillniendormieLabel.Text = 'Neither alert nor sleepy / Niéveillé ni endormie';
            app.SomesignsofsleepinessQuelquessignesdesomnolenceLabel = uilabel(app.GridLayout);
            app.SomesignsofsleepinessQuelquessignesdesomnolenceLabel.Text = 'Some signs of sleepiness/ Quelques signes de somnolence';
            app.SleepybutnoefforttokeepalertLabel = uilabel(app.GridLayout);
            app.SleepybutnoefforttokeepalertLabel.Text = 'Sleepy but no effort to keep alert/ Sommeil mais aucun effort pour rester éveillé';
            app.SleepysomeefforttokeepalertLabel = uilabel(app.GridLayout);
            app.SleepysomeefforttokeepalertLabel.Text = 'Sleepy some effort to keep alert/ Sonneil un effort pour rester éveillé';
            app.VerysleepygreatefforttokeepalertfightingsleepLabel = uilabel(app.GridLayout);
            app.VerysleepygreatefforttokeepalertfightingsleepLabel.Text = {'Very sleepy great effort to keep alertfighting sleep/ Très somnolent grand effort pour rester '; 'éveillé, lutte contre le sommeil'};
            app.PleaseindicatehowyouarefeelingrightnowLabel = uilabel(app.GridLayout);
            app.PleaseindicatehowyouarefeelingrightnowLabel.Text = 'Please indicate how you are feeling right now/ Veuillez indiquer comment vous vous sentez en ce moment :';
            app.DoneFiniButton = uibutton(app.GridLayout, 'push');
            app.DoneFiniButton.ButtonPushedFcn = createCallbackFcn(app, @DoneFiniButtonPushed, true);
            app.DoneFiniButton.Visible = 'off';
            app.DoneFiniButton.Text = {'Done! Fini!'; ''};
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '';
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = '';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = '';
            app.Button4 = uiradiobutton(app.ButtonGroup);
            app.Button4.Text = '';
            app.Button5 = uiradiobutton(app.ButtonGroup);
            app.Button5.Text = '';
            app.Button6 = uiradiobutton(app.ButtonGroup);
            app.Button6.Text = '';
            app.Button7 = uiradiobutton(app.ButtonGroup);
            app.Button7.Text = '';
            app.Button8 = uiradiobutton(app.ButtonGroup);
            app.Button8.Text = '';
            app.Button9 = uiradiobutton(app.ButtonGroup);
            app.Button9.Text = '';
            app.Button9_2 = uiradiobutton(app.ButtonGroup);
            app.Button9_2.Visible = 'off';
            app.Button9_2.Text = '';
            app.Button9_2.Value = true;
            app.VigilanceVigilanceLabel = uilabel(app.GridLayout);
            app.VigilanceVigilanceLabel.Text = 'Vigilance / Vigilance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KSS_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end