classdef RSME_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MentalEffortEffortMentaleLabel  matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
    end

    
    properties (Access = private)
        RSMEdata % Description
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idz, session, purpose)
       
                 app.Label.Text = {'Please indicate, by marking the '; 'vertical axis below, how high your degree of effort '; 'is right now .'; 'Veuillez indiquer, en cochant la case ' ; 'l''axe vertical ci-dessous, quel est votre degré d''effort'   };   %changes the instructions given the purpose  
       
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
 assignin('base','RSME_data', value);  %assigns the output to the normal workspace
                     app.DoneButton.Visible='on';
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
  
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 150];
            app.Slider.MajorTicks = [2 13 25 37 57 71 85 102 112];
            app.Slider.MajorTickLabels = {'Absolutely no effort/ Absolument aucun effort', 'Almost no effort/ Presque aucun effort', 'A little effort/ Un petit effort', 'Some effort/ Quelques efforts', 'Rather Much effort/ Plutôt beaucoup d''efforts', 'Considerable effort/ Un effort considérable', 'Great effort/ Grand effort', 'Very great effort/ Un très grand effort', 'Extreme effort/ Un effort extrême', ''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Please indicate by marking the vertical axis below '; 'how much effort it took for you to complete the task '; 'you''ve just finished/ '; ''; 'Veuillez indiquer en cochant l''axe vertical, '; 'l''effort qu''il vous a fallu pour accomplir la tâche que'; ' vous venez de terminer'};
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Visible = 'off';
            app.DoneButton.Text = 'Done!';
            app.MentalEffortEffortMentaleLabel = uilabel(app.GridLayout);
            app.MentalEffortEffortMentaleLabel.Text = 'Mental Effort / Effort Mentale';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSME_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end