classdef demo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        DoneFiniButton  matlab.ui.control.Button
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        text6           matlab.ui.control.Label
        work            matlab.ui.control.ListBox
        checkeducation  matlab.ui.control.CheckBox
        education       matlab.ui.control.ListBox
        text4           matlab.ui.control.Label
        checkgender     matlab.ui.control.CheckBox
        gender          matlab.ui.control.ListBox
        text3           matlab.ui.control.Label
        checkage        matlab.ui.control.CheckBox
        text2           matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % Description
        idz % Description
        session% Description
    end
    
    methods (Access = private)
        function slider1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
             
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function demo_OpeningFcn(app, idz, session)
            % Create GUIDE-style callback args - Added by Migration Tool
[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to demo (see VARARGIN)
            
            % Choose default command line output for demo
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
     
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           app.data.age = app.EditField.Value;
            app.checkage.Visible = 'on';
        end

        % Value changed function: gender
        function genderValueChanged(app, event)
            app.data.gender = app.gender.Value;
             app.checkgender.Visible = 'on';
        end

        % Value changed function: education
        function educationValueChanged(app, event)
            app.data.education = app.education.Value;
              app.checkeducation.Visible = 'on';
        end

        % Value changed function: work
        function workValueChanged(app, event)
            app.data.work = app.work.Value;
             app.DoneFiniButton.Visible = 'on';
            
        end

        % Value changed function: checkgender
        function checkgenderValueChanged(app, event)
            value = app.checkgender.Value;
            app.text4.Visible = 'on';
            app.education.Visible = 'on';
          
            
        end

        % Value changed function: checkage
        function checkageValueChanged(app, event)
            value = app.checkage.Value;
             app.text3.Visible = 'on';
            app.gender.Visible = 'on';
          
        end

        % Value changed function: checkeducation
        function checkeducationValueChanged(app, event)
            value = app.checkeducation.Value;
             app.text6.Visible = 'on';
            app.work.Visible = 'on';
           
        end

        % Button pushed function: DoneFiniButton
        function DoneFiniButtonPushed(app, event)
        dataa=app.data;

 assignin('base','demo_data', dataa);
        close(app.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'demo';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Please enter your Age in Years/'; 'Veuillez entrer votre âge en années'; ''; ''};
            app.checkage = uicheckbox(app.figure1);
            app.checkage.ValueChangedFcn = createCallbackFcn(app, @checkageValueChanged, true);
            app.checkage.Tag = 'checkage';
            app.checkage.Visible = 'off';
            app.checkage.Text = '';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Visible = 'off';
            app.text3.Text = {'Please indicate your Gender/'; 'Veuillez indiquer votre sexe'};
            app.gender = uilistbox(app.figure1);
            app.gender.Items = {'Male/ homme', 'Female/ femme', 'Undefined/ indéfinie'};
            app.gender.ValueChangedFcn = createCallbackFcn(app, @genderValueChanged, true);
            app.gender.Tag = 'gender';
            app.gender.Visible = 'off';
            app.gender.Value = {};
            app.checkgender = uicheckbox(app.figure1);
            app.checkgender.ValueChangedFcn = createCallbackFcn(app, @checkgenderValueChanged, true);
            app.checkgender.Tag = 'checkgender';
            app.checkgender.Visible = 'off';
            app.checkgender.Text = '';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Visible = 'off';
            app.text4.Text = {'Please Indicate the highest level of'; ' Education you have achieved/ '; 'Veuillez indiquer le niveau le '; 'd''études le plus élevé que vous avez atteint'};
            app.education = uilistbox(app.figure1);
            app.education.Items = {'Highschool/ Lycée', 'Aprenticeship/ Apprentissage/ ', 'Underegraduate Degree (Bachelors)/Diplôme de premier cycle (licence)', 'Graduate Degree (Masters)/Diplôme d''études supérieures (Masters)', 'PhD/ Doctorant'};
            app.education.ValueChangedFcn = createCallbackFcn(app, @educationValueChanged, true);
            app.education.Tag = 'education';
            app.education.Visible = 'off';
            app.education.Value = {};
            app.checkeducation = uicheckbox(app.figure1);
            app.checkeducation.ValueChangedFcn = createCallbackFcn(app, @checkeducationValueChanged, true);
            app.checkeducation.Tag = 'checkeducation';
            app.checkeducation.Visible = 'off';
            app.checkeducation.Text = '';
            app.work = uilistbox(app.figure1);
            app.work.Items = {'Student/ Étudiant', 'Employed/ Employé', 'Unemployed/ Chômeur', 'other/ autre'};
            app.work.ValueChangedFcn = createCallbackFcn(app, @workValueChanged, true);
            app.work.Tag = 'work';
            app.work.Visible = 'off';
            app.work.Value = {};
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Visible = 'off';
            app.text6.Text = {'Please Indicate what best describes'; 'your current situation./'; ' Veuillez indiquer ce qui décrit le mieux'; 'votre situation actuelle.'};
            app.EditFieldLabel = uilabel(app.figure1);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.figure1, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = 'age';
            app.DoneFiniButton = uibutton(app.figure1, 'push');
            app.DoneFiniButton.ButtonPushedFcn = createCallbackFcn(app, @DoneFiniButtonPushed, true);
            app.DoneFiniButton.Visible = 'off';
            app.DoneFiniButton.Text = 'Done! Fini!';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)demo_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end